% description: Application permettant de dimensionner une communauté d’Energie.
% Copyright (C) 2024  Gaelle Sellier
% This program is free software: you can redistribute it and/or modify
% it under the terms of the GNU General Public License as published by
% the Free Software Foundation, either version 3 of the License, or
% (at your option) any later version.
% This program is distributed in the hope that it will be useful,
% but WITHOUT ANY WARRANTY; without even the implied warranty of
% MERCHANTABILITY or FITNESS FOR A PARTICULAR PURPOSE.  See the
% GNU General Public License for more details.
% You should have received a copy of the GNU General Public License
% along with this program.  If not, see <https://www.gnu.org/licenses/>.
classdef DimCE124 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DimCEUIFigure                  matlab.ui.Figure
        FichierMenu                    matlab.ui.container.Menu
        LancerloptimisationMenu        matlab.ui.container.Menu
        ConfigurationpardfaultMenu     matlab.ui.container.Menu
        ExporterlesdonnesdoptimisationMenu  matlab.ui.container.Menu
        ExporterlesdonnesdelaCEperso   matlab.ui.container.Menu
        QuitterMenu                    matlab.ui.container.Menu
        AideMenu                       matlab.ui.container.Menu
        InterfaceMenu                  matlab.ui.container.Menu
        AproposdeDimCEMenu             matlab.ui.container.Menu
        TabGroup                       matlab.ui.container.TabGroup
        SimulationCETab                matlab.ui.container.Tab
        ChoixtypedesimulationButtonGroup  matlab.ui.container.ButtonGroup
        SimulationsurCEpersonnaliseButton  matlab.ui.control.RadioButton
        SimulationrapideButton         matlab.ui.control.RadioButton
        Fournisseur                    matlab.ui.control.Label
        Date                           matlab.ui.control.Label
        tariffourisseur                matlab.ui.control.Table
        DimCEInterfacedoptimisationpourCommunautdnergieLabel  matlab.ui.control.Label
        SimulationrapideDonnesphysiquesCEPanel  matlab.ui.container.Panel
        Affprodan                      matlab.ui.control.Label
        rightarrowProductionannuelletotaleLabel  matlab.ui.control.Label
        kWhLabel_6                     matlab.ui.control.Label
        kWhLabel_5                     matlab.ui.control.Label
        kWLabel                        matlab.ui.control.Label
        Consommationannuelledunconsommateur  matlab.ui.control.NumericEditField
        ConsommationannuelledunconsommateurLabel  matlab.ui.control.Label
        Nombremaximundeconsommateur    matlab.ui.control.NumericEditField
        NombremaximundeconsommateurEditFieldLabel  matlab.ui.control.Label
        Consommationannuelledunproducteur  matlab.ui.control.NumericEditField
        ConsommationannuelledunproducteurEditFieldLabel  matlab.ui.control.Label
        Nombredeproducteur             matlab.ui.control.NumericEditField
        NombredeproducteurEditFieldLabel  matlab.ui.control.Label
        Puissancetotaleinstalle        matlab.ui.control.NumericEditField
        PuissanceinstalleLabel         matlab.ui.control.Label
        TypedeproductionDropDown       matlab.ui.control.DropDown
        TypedeproductionDropDownLabel  matlab.ui.control.Label
        ValeurspourintgralitdusurplusVenduPanel  matlab.ui.container.Panel
        GainparproducteursitoutelnergieensurplusvenduedanslaCE  matlab.ui.control.EditField
        GainparproducteursitoutelnergieensurplusvenduedanslaCELabel  matlab.ui.control.Label
        GainparconsoAuMax              matlab.ui.control.EditField
        GainparproducteurloptimumEditFieldLabel_2  matlab.ui.control.Label
        PartmaximumdelnergietotaleproduitevenduelaCE  matlab.ui.control.EditField
        PartmaximumdelnergietotaleproduitevenduelaCELabel  matlab.ui.control.Label
        NombreconsoAuMAX               matlab.ui.control.NumericEditField
        GainparproducteurloptimumEditField_2Label  matlab.ui.control.Label
        ValeursOptimalesPanel          matlab.ui.container.Panel
        Gainparproducteurloptimum      matlab.ui.control.EditField
        GainparproducteurloptimumEditFieldLabel_3  matlab.ui.control.Label
        Gainparconsommateurloptimum    matlab.ui.control.EditField
        GainparconsommateurloptimumEditFieldLabel  matlab.ui.control.Label
        NombredeConsommateuroptimum    matlab.ui.control.NumericEditField
        NombredeConsommateuroptimum1Label  matlab.ui.control.Label
        GraphiqueettablePanel          matlab.ui.container.Panel
        ChoixGraphique                 matlab.ui.control.DropDown
        UITable                        matlab.ui.control.Table
        UIAxes                         matlab.ui.control.UIAxes
        DonnesconomiquesCEPanel        matlab.ui.container.Panel
        QuitterButton                  matlab.ui.control.Button
        ConfigurationpardfaultButton   matlab.ui.control.Button
        LancerloptimisationButton      matlab.ui.control.Button
        ChoixduFournisseurDropDown     matlab.ui.control.DropDown
        ChoixduFournisseurDropDownLabel  matlab.ui.control.Label
        kWhLabel_4                     matlab.ui.control.Label
        kWhLabel_3                     matlab.ui.control.Label
        PrixdachatdellectricitdelaCEauproducteur  matlab.ui.control.NumericEditField
        PrixdachatdellectricitdelaCEauproducteurLabel  matlab.ui.control.Label
        PrixventedellectricitdelaCEauconsommateur  matlab.ui.control.NumericEditField
        PrixventedlectricitdelaCEauconsommateurLabel  matlab.ui.control.Label
        PersonnaliserlaCETab           matlab.ui.container.Tab
        InformationCEPanel             matlab.ui.container.Panel
        VolumeTotalEchangCEEditField   matlab.ui.control.EditField
        VolumeTotalEchangCELabel       matlab.ui.control.Label
        NombredemembreEditField        matlab.ui.control.EditField
        NombredemembreEditFieldLabel   matlab.ui.control.Label
        ConsommationTotaleEditField    matlab.ui.control.EditField
        ConsommationTotalCELabel       matlab.ui.control.Label
        ProductionTotaleEditField      matlab.ui.control.EditField
        ProductionTotalCELabel         matlab.ui.control.Label
        UITable3                       matlab.ui.control.Table
        UIAxesCE                       matlab.ui.control.UIAxes
        ButtonSupprimer                matlab.ui.control.Button
        ButtonImporter                 matlab.ui.control.Button
        ButtonExporter                 matlab.ui.control.Button
        ButtonEffacer                  matlab.ui.control.Button
        UITableCE                      matlab.ui.control.Table
        NouveaumembrePanel             matlab.ui.container.Panel
        Consommationannuelleindust     matlab.ui.control.NumericEditField
        kWhLabel_8                     matlab.ui.control.Label
        ConsommationannuelleprofilindustrielLabel  matlab.ui.control.Label
        Buttonajouter                  matlab.ui.control.Button
        NomdumembreLabel               matlab.ui.control.Label
        ConsommationannuelleprofilparticulierLabel  matlab.ui.control.Label
        Nom                            matlab.ui.control.EditField
        Consommationannuelleparti      matlab.ui.control.NumericEditField
        kWhLabel_7                     matlab.ui.control.Label
        ProductiondumembrePanel        matlab.ui.container.Panel
        PuissanceinstalleLabel_5       matlab.ui.control.Label
        PuissanceinstalleLabel_4       matlab.ui.control.Label
        PuissanceinstalleLabel_3       matlab.ui.control.Label
        PuissanceinstalleBio           matlab.ui.control.NumericEditField
        kWLabel_8                      matlab.ui.control.Label
        PuissanceinstalleSol           matlab.ui.control.NumericEditField
        kWLabel_7                      matlab.ui.control.Label
        PuissanceinstalleEol           matlab.ui.control.NumericEditField
        kWLabel_6                      matlab.ui.control.Label
        BatteriedumembrePanel          matlab.ui.container.Panel
        PuissanceinstalleLabel_8       matlab.ui.control.Label
        Puissancebat                   matlab.ui.control.NumericEditField
        kWLabel_11                     matlab.ui.control.Label
        kWhLabel_9                     matlab.ui.control.Label
        Capacitebat                    matlab.ui.control.NumericEditField
        CapacitbatterieinstalleLabel   matlab.ui.control.Label
        DimensionnementinstallationetbatterieTab  matlab.ui.container.Tab
        DimensionnementBatteriePanel   matlab.ui.container.Panel
        DimensionnementInstallationenfonctiondelaconsommationPanel  matlab.ui.container.Panel
        downarrowdownarrowdownarrowLabel  matlab.ui.control.Label
        Puissancetotaleainstalle       matlab.ui.control.NumericEditField
        PuissanceinstalleLabel_7       matlab.ui.control.Label
        Affprodan_2                    matlab.ui.control.Label
        rightarrowProductionannuelletotaleLabel_2  matlab.ui.control.Label
        kWLabel_10                     matlab.ui.control.Label
        ConsommationannuelleDim        matlab.ui.control.NumericEditField
        ConsommationannuelleLabel      matlab.ui.control.Label
        TypedeproductionDropDown_2     matlab.ui.control.DropDown
        TypedeproductionDropDown_2Label  matlab.ui.control.Label
        kWhLabel_10                    matlab.ui.control.Label
        UIAxesdiminst                  matlab.ui.control.UIAxes
    end


    % Public properties that correspond to the Simulink model
    properties (Access = public, Transient)
        Simulation simulink.Simulation
    end


    properties (Access = public)
            autoconsoCE       % autoconsomation de la CE pour une valeur donner de Nbre de consommateur
            autosufCE         % autosuffisance de la CE pour une valeur donner de Nbre de consommateur
            autosufConso      % autosuffisance d un consomateur dans la CE pour une valeur donner de Nbre de consommateur
            autoconsoProd     % autoconsomation d un producteur initiale
            autosufProd       % autosuffisance d un producteur
            autosufInterProd  % autosufficance acquise interproducteur grace a la CE intiale
            prodTotalCE       % Production total de la CE 
            SurplusprodCE     % Part des surplus vendu par le producteur a la CE
            facteurDimCE      % facteur de dimensionnement de la CE pour une valeur donner de Nbre de consommateur
            facteurDimProd    % facteur de dimensionnement d un producteur
            annuelConsoCE     % Consommation annuelle de la CE pour une valeur donner de Nbre de consommateur
            iCount            % variable de comptage 
            VOpti             % pente de croissance de gain producteur pour l optimun 
            Pas               % nombre de consommateur rajouter a chaque etape
            NbrConso          % nombre de consommateur a l etape icount .
            NbrConsoMax       % nombre de consommateur maximun.
            optiFound         % varible bouleen qui signale si l optimun a ete trouver.
            nbrConsoSurplusMax   % Nbr de conso pour surplus max a l etape icount
            nbrprod           % nombre de producteur
            nbrConsoOpti      % nombre de consommateur a l optimun
            Puissanceprod     % puissance d un producteur
            prodAnnuel        % Production annuel d un producteur em Kwh
            surplusMax        % Surplus  au maximun d energie vendue
            autosufMax        % autosuffisance des consomateur au maximun d energie vendue
            annuelConsoProd   % Consommation annuelle du meilleur producteur
            annuelConsoConso  % Consommation annuelle d un consommateur
            prixVente         % prix de vente  de lelectriciter par la CE  au consommateur 
            prixAchat         % prix d achat   de lelectriciter par la CE  au producteur 
            tabOpti           % tableau  des valeur pour chaque  icount en carractere pour affichage
            tabOptiNum        % tableau  des valeur pour chaque  icount  pour calcule
            tabPrixFour       % tableau des prix en fonction des fournisseur
            tabPrix           % tableau des prix pour le fournisseur choisis
            tabPrixConso      % tableau des prix des different poste pour facture du consomateur
            tabPrixProd       % tableau des prix des different poste pour facture du Producteur
            tabPrixConsoCE    % tableau des prix des different poste pour facture du consomateur dans la CE
            tabPrixProdCE     % tableau des prix des different poste pour facture du Producteur dans la CE
            tabFactDim        % tableau du facteur de dimmensionement 
            prixEnergieConso  % Prix achat energie consomateur sans CE
            prixEnergieConsoCE% Prix achat energie consomateur avec CE
            prixEnergieProd   % Prix achat energie producteur sans CE
            prixEnergieProdCE % Prix achat energie Produtceur avec CE
            pathFact          % Adresse de du fichier de dimensionnemnt
            pathTarif         % Adresse de du fichier de tarif
            Pathexe           % adresse de DimCe.exe
            PIBio             %Vecteur Puissance installer biomethanisation membre kw 
            PIEol             %Vecteur Puissance installer eolienne membre en KW
            PISol             %Vecteur Puissance installer solaire membre (kw)
            CAInd             %Vecteur Consommation annuel  industri membre (Kwh):
            CEPerso           %Table regroupant  les membres de la CE personnalisee.
            CA                %Vecteur Consommation annuel parti menbre (Kwh):
            autosuf           %Vecteur autosufissance initial part menbre
            autocons          %Vecteur autoconsommation initial part menbre 
            autosuffiF        %Vecteur autosufissance CE part menbre
            autoconsomF       %Vecteur autoconsommation CE part menbre
            Resid             %matrice residu consommation part 1/4h part menbre 
            Su                %matrice surplus production part 1/4h part menbre
            Residf            %matrice residu consommation dans CE part 1/4h part menbre
            Suf               %matrice surplus production dans CE part 1/4h part menbre
            Prod              %Vecteur Production total part menbre
            VMembre           %Vecteur des numeros part menbre
            NbrMembre         %Nomnbre de menbre initiale de la CE
            SurplusvenduCE    %Vecteur des surplus vendu a la CE part menbre
            autoSuffAcquise   %Vecteur de l autoconsomation acquise grace a la CE part menbre
            CEVideStop        %Flag pour arreter si la CE perso est vide
            PIBat             %Vecteur des Puissance installer batterie
            CapaBat           %Vecteur des Capaciter installer batterie
            SocQuart          %Matrice de l etat de charge de la batterie part 1/4h part menbre
            consoCEmensuel    %Matrice de la consomation mensuel a la CE  part menbre
    end
    
    methods (Access = private)
        function [thePath]=showpath(~)
                % Show EXE path:
                if isdeployed % Stand-alone mode.
                [~, result] = system('set PATH');
                   thePath = char(regexpi(result, 'Path=(.*?);', 'tokens', 'once'));
                else % Running from MATLAB.
                   thePath=pwd;
                end
                return
        end
        function tableautarif = importtarif(app,filename, dataLines)
            %IMPORTFILE Import data from a text file
            %  TABLEAUTARIF = IMPORTFILE(FILENAME) reads data from text file
            %  FILENAME for the default selection.  Returns the data as a table.
            %
            %  TABLEAUTARIF = IMPORTFILE(FILE, DATALINES) reads data for the
            %  specified row interval(s) of text file FILENAME. Specify DATALINES as
            %  a positive scalar integer or a N-by-2 array of positive scalar
            %  integers for dis-contiguous row intervals.
            %
            %  Example:
            %  tableautarif = importfile("C:\Users\Lio\Desktop\Energie\Matlab\DimceMatlab\tableautarif.csv", [2, Inf]);
            %
            %  See also READTABLE.
            %
            % Auto-generated by MATLAB on 20-Dec-2024 16:55:05

            %% Input handling

            % If dataLines is not specified, define defaults
            if nargin < 2
                dataLines = [2, Inf];
            end

            %% Set up the Import Options and import the data
            opts = delimitedTextImportOptions("NumVariables", 11);

            % Specify range and delimiter
            opts.DataLines = dataLines;
            opts.Delimiter = ";";

            % Specify column names and types
            opts.VariableNames = ["VarName1", "VarName2", "Mega", "Luminus", "Engie", "Totalenergie","Bolt","LuminusBXL","TotalBXL","Eneco", "Date"];
            opts.VariableTypes = ["string", "string", "double", "double", "double", "double","double","double","double","double", "string"];

            % Specify file level properties
            opts.ExtraColumnsRule = "ignore";
            opts.EmptyLineRule = "read";

            % Specify variable properties
            opts = setvaropts(opts, ["VarName1", "VarName2", "Date"], "WhitespaceRule", "preserve");
            opts = setvaropts(opts, ["VarName1", "VarName2", "Date"], "EmptyFieldRule", "auto");
            opts = setvaropts(opts, ["Mega", "Luminus", "Engie", "Totalenergie","Bolt","LuminusBXL","TotalBXL","Eneco"], "DecimalSeparator", ",");
            opts = setvaropts(opts, ["Mega", "Luminus", "Engie", "Totalenergie","Bolt","LuminusBXL","TotalBXL","Eneco"], "ThousandsSeparator", ".");

            % Import the data
            tableautarif = readtable(filename, opts);
            app.tabPrixFour=tableautarif;
           %Strucutre du tableau de tarif:
            % frais de distribution Resa
            % frais de transport Resa
            % frais de distribution terme fixe Resa
            % Redevance de raccordement 
            % Accise spéciale
            % Cotisation sur l'énergie
            % Cotisation Verte (€/an) 
            % Tarif Prosumer Resa
            % Redevance fixe (€/an) 
            % Forfait panneaux solaires (€/kVA par mois)
            % Forfait partage d'énergie (€/an) 
           

        end
        
        
        function optiCe(app)
           

            app.tabPrix = get(app.tariffourisseur,'Data');
            app.tabOpti=string;
            thisProgress = uiprogressdlg( app.DimCEUIFigure, 'Title', 'Calcul en cours...', 'Message', 'Veuillez patienter.', 'Value', 0,'Cancelable','on','CancelText','Annuler','Icon','Carte 9 communes K2.png');
           %on choisi le plus gros vendeur  pour l affichage
            [~,I]=max((1-app.autocons)*app.Prod');     
            app.annuelConsoProd = app.CA(1,I)+app.CAInd(1,I);
            while app.iCount <= app.NbrConsoMax 
                if thisProgress.CancelRequested
                    
                  break;
                end   
         % rem affichage de la barre de chargement
        
          thisProgress.Message = 'Veuillez patienter';
          thisProgress.Value = app.iCount/app.NbrConsoMax ;


           %CE a l etape icount:
            app.Resid=zeros(35136,app.NbrMembre+app.iCount);
            app.Su=zeros(35136,app.NbrMembre+app.iCount);
            app.Residf=zeros(35136,app.NbrMembre+app.iCount);
            app.Suf=zeros(35136,app.NbrMembre+app.iCount);
            app.autosuf=1:app.NbrMembre+app.iCount;
            app.autocons=1:app.NbrMembre+app.iCount;
            app.autosuffiF=1:app.NbrMembre+app.iCount;
            app.autoconsomF=1:app.NbrMembre+app.iCount;
            app.autoSuffAcquise=1:app.NbrMembre+app.iCount;
            app.Prod=1:app.NbrMembre+app.iCount;
            app.PIEol=[app.PIEol 0];
            app.PISol=[app.PISol 0];
            app.PIBio=[app.PIBio 0];
            app.CAInd=[app.CAInd 0];
            app.CA=[app.CA app.annuelConsoConso];
            app.PIBat=[app.PIBat 0];
            app.CapaBat=[app.CapaBat 0];
            for i=1:1:app.NbrMembre+app.iCount
                [app.autosuf(i),app.autocons(i),app.Resid(:,i),app.Su(:,i),app.Prod(i),app.SocQuart(:,i)]= Dimdata(app,app.PIEol(i),app.PISol(i),app.PIBio(i),app.CAInd(i),app.CA(i),app.PIBat(i),app.CapaBat(i));
            end
            Sutotquart=zeros(35136,1);
            Residtotquart=zeros(35136,1);
            for i=1:35136
                Sutotquart(i,1)=sum (app.Su(i,:));
                Residtotquart(i,1)=sum (app.Resid(i,:));
                if Residtotquart(i,1)==0
                    for j=1:app.NbrMembre+app.iCount
                        app.Suf(i,j)=app.Su(i,j);
                        app.Residf(i,j)=0;
                    end
                end
                if Residtotquart(i,1)>=Sutotquart(i,1)

                    a=Sutotquart(i,1)/Residtotquart(i,1);
                    for j=1:app.NbrMembre+app.iCount
                        if app.Resid(i,j)~=0
                            app.Residf(i,j)=app.Resid(i,j)-(a*(app.Resid(i,j)));
                        else
                            app.Residf(i,j)=0;
                        end
                        app.Suf(i,j)=0;
                    end
                end
                if Residtotquart(i,1) < Sutotquart(i,1)
                    b=(Sutotquart(i,1)-Residtotquart(i,1))/Sutotquart(i,1);
                    for j=1:app.NbrMembre+app.iCount
                        if app.Su(i,j)~=0
                            app.Suf(i,j)=(b*app.Su(i,j));
                        else
                            app.Suf(i,j)=0;
                        end
                        app.Residf(i,j)=0;
                    end
                end
            end
            for i=1:app.NbrMembre+app.iCount
                app.autosuffiF(i)  = ((app.CA(i)+app.CAInd(i))-sum(app.Residf(:,i)))/(app.CA(i)+app.CAInd(i));
                app.autoconsomF(i) = (app.Prod(i)-sum(app.Suf(:,i)))/app.Prod(i);
                app.autoSuffAcquise(i)=(sum(app.Resid(:,i))-sum(app.Residf(:,i)))/(app.CA(i)+app.CAInd(i));
            end
            app.SurplusvenduCE= app.autoconsomF-app.autocons;
            app.SurplusvenduCE= fillmissing(app.SurplusvenduCE,'constant',0);
            app.autosuffiF= fillmissing(app.autosuffiF,'constant',0);
            app.autoSuffAcquise= fillmissing(app.autoSuffAcquise,'constant',0);
            app.autocons=fillmissing(app.autocons,'constant',0);
            app.autosuf=fillmissing(app.autosuf,'constant',0);
            %autosuffissance acquise  pour l'affichage du plus gros vendeur 
            app.autosufInterProd=app.autoSuffAcquise(I);
            if app.annuelConsoProd>0
            app.autosufProd=app.autosuf(I);
            app.autoconsoProd=app.autocons(I);
            else
            app.autoconsoProd=0;
            app.autosufProd=0;
            end
            % Rem construction des tableaux
             %1er colonne
            
            app.NbrConso = (app.iCount * app.Pas);
            app.tabOpti(app.iCount,1) = app.NbrConso;
            app.tabOptiNum(app.iCount,1)=app.NbrConso;

            %2eme colonne autosuffisance d un consomateur type dans la CE

            app.annuelConsoCE=(app.NbrConso*app.annuelConsoConso)+(app.nbrprod*app.annuelConsoProd);
            app.autosufConso= app.autoSuffAcquise(app.NbrMembre+1);
            app.tabOpti(app.iCount,2)=sprintf('%0.2f%%',app.autosufConso*100);
            app.tabOptiNum(app.iCount,2)=app.autosufConso;

            %3eme colonne surplus vendu
            app.SurplusprodCE = app.SurplusvenduCE(I); %le plus gros surplus est afficher 
            app.tabOpti(app.iCount,3)=sprintf('%0.2f%%',app.SurplusprodCE*100);
            app.tabOptiNum(app.iCount,3)=app.SurplusprodCE;
            %4eme colonne Gain consommateur
   
            app.prixEnergieConso =app.tabPrix(2,1).*app.annuelConsoConso;
            app.prixEnergieConsoCE  = app.tabPrix(2,1)*((1-app.autosufConso)*app.annuelConsoConso)+(app.PrixventedellectricitdelaCEauconsommateur.Value*app.autosufConso*app.annuelConsoConso);
            app.tabOpti(app.iCount,4)=sprintf('%0.2f €%',app.prixEnergieConso-app.prixEnergieConsoCE-app.tabPrix(13,1));
            app.tabOptiNum(app.iCount,4)=app.prixEnergieConso-app.prixEnergieConsoCE-app.tabPrix(13,1);

            %5eme colonne Gain Producteur
            app.prixEnergieProd =(app.tabPrix(2,1)*(1-app.autosufProd)*app.annuelConsoProd)-(app.tabPrix(1,1)*(app.Prod(I)*(1-app.autoconsoProd)));
            app.prixEnergieProdCE  =(app.tabPrix(2,1)*(1-app.autosufProd-app.autosufInterProd)*app.annuelConsoProd)+(app.PrixventedellectricitdelaCEauconsommateur.Value*app.autosufInterProd*app.annuelConsoProd)-(app.PrixdachatdellectricitdelaCEauproducteur.Value*(app.SurplusprodCE*app.Prod(I)))-(app.tabPrix(1,1)*(app.Prod(I)*(1-app.autoconsomF(I))));
            app.tabOpti(app.iCount,5)=sprintf('%0.2f €%',app.prixEnergieProd-app.prixEnergieProdCE-app.tabPrix(13,1));
            app.tabOptiNum(app.iCount,5)=app.prixEnergieProd-app.prixEnergieProdCE-app.tabPrix(13,1);
            
           if app.iCount==1 
               % rien faire
           else
               % test pour construire le maximun de surplus
              if app.tabOptiNum(app.iCount-1,3) < app.tabOptiNum(app.iCount,3) && app.tabOptiNum(app.iCount,3)-app.tabOptiNum(app.iCount-1,3)>0.0001
                 app.nbrConsoSurplusMax=app.iCount;
                 app.surplusMax=app.tabOptiNum(app.iCount,3);
                 app.autosufMax= app.tabOptiNum(app.iCount,3);
              end
              app.VOpti  = app.tabOptiNum(app.iCount,5)-app.tabOptiNum(app.iCount-1,5);
           % Rem test pour construire le pointoptimum
               if app.VOpti < (0.2 * (app.tabOptiNum(2,5)-app.tabOptiNum(1,5))) &&  ~app.optiFound
                  app.nbrConsoOpti  = app.iCount - 1;
                  app.optiFound = true;
               end
               if app.iCount == app.NbrConsoMax && ~app.optiFound
                  app.nbrConsoOpti  = app.iCount;
                  app.optiFound = true;
               end
               
           end 
           if app.NbrConsoMax == 1
              app.nbrConsoOpti=1;
              app.optiFound = true;
           end
            app.iCount=app.iCount+1;
            end   
            close(thisProgress);
         app.LancerloptimisationButton.Text="Lancer l'optimisation";       
         app.UITable.Data=app.tabOpti;
         app.ChoixGraphique.Enable=true;
         %affichage du graphique par defaut.
         app.ChoixGraphique.Value="Graphique du surplus et de autosuffisance";
         app.UIAxes.YLabel.String='%';
         plot(app.UIAxes,app.tabOptiNum(:,1),app.tabOptiNum(:,2),app.tabOptiNum(:,1),app.tabOptiNum(:,3));
         legend(app.UIAxes,{'Autosuffisance conso.' 'Surplus Vendu CE'},'Location','best'); 
         %affichage des valeurs maxsurplus vendu
         app.GainparproducteursitoutelnergieensurplusvenduedanslaCE.Value=app.tabOpti(app.nbrConsoSurplusMax,5);
         app.GainparconsoAuMax.Value=app.tabOpti(app.nbrConsoSurplusMax,4);
         app.PartmaximumdelnergietotaleproduitevenduelaCE.Value=app.tabOpti(app.nbrConsoSurplusMax,3);
         app.NombreconsoAuMAX.Value=app.nbrConsoSurplusMax;
         %affichage des valeurs optimun 
         app.NombredeConsommateuroptimum.Value= app.nbrConsoOpti; 
         app.Gainparproducteurloptimum.Value=app.tabOpti(app.nbrConsoOpti,5);
         app.Gainparconsommateurloptimum.Value=app.tabOpti(app.nbrConsoOpti,4);
        end 
        function results = caluclePrix(app,avecCE,conso)
            if ~avecCE && conso
            matricetrans=diag([0 1 app.annuelConsoConso app.annuelConsoConso 1 app.annuelConsoConso app.annuelConsoConso app.annuelConsoConso app.annuelConsoConso 0 1 0 0]);
            matriceprix=matricetrans*app.tabPrix;
            matriceprix(1)=[];
            matriceprix(1)=app.tabPrix(2,1).*app.annuelConsoConso;
            results=matriceprix;
            
            else
            if avecCE && conso
            matricetrans=diag([0 1 app.annuelConsoConso app.annuelConsoConso 1 app.annuelConsoConso app.annuelConsoConso app.annuelConsoConso app.annuelConsoConso 0 1 0 1]);
            matriceprix=matricetrans*app.tabPrix;
            matriceprix(1)=[];
            matriceprix(1)= app.tabPrix(2,1)*((1-app.tabOptiNum(app.nbrConsoOpti,2))*app.annuelConsoConso)+(app.PrixventedellectricitdelaCEauconsommateur.Value*(app.tabOptiNum(app.nbrConsoOpti,2))*app.annuelConsoConso);
            results=matriceprix;  
            else
            if avecCE && ~conso 
            matricetrans=diag([0 1 app.annuelConsoProd*(1-app.autosufProd) app.annuelConsoProd*(1-app.autosufProd) 1 app.annuelConsoProd*(1-app.autosufProd)  app.annuelConsoProd*(1-app.autosufProd)  app.annuelConsoProd*(1-app.autosufProd)  app.annuelConsoProd*(1-app.autosufProd)  0 1 0 1]);
            matriceprix=matricetrans*app.tabPrix;
            matriceprix(1)=[];
            matriceprix(1)=(app.tabPrix(2,1)*(1-app.autosufProd-app.autosufInterProd)*app.annuelConsoProd)+(app.PrixventedellectricitdelaCEauconsommateur.Value*app.autosufInterProd*app.annuelConsoProd)-(app.PrixdachatdellectricitdelaCEauproducteur.Value*(app.annuelConsoConso*(app.tabOptiNum(app.nbrConsoOpti,2))*app.nbrConsoOpti/app.nbrprod))-(app.tabPrix(1,1)*((app.prodAnnuel*(1-app.autoconsoProd))-(app.annuelConsoConso*(app.tabOptiNum(app.nbrConsoOpti,2))*app.nbrConsoOpti/app.nbrprod)));
            results=matriceprix;
            else
            matricetrans=diag([0 1 app.annuelConsoProd*(1-app.autosufProd)  app.annuelConsoProd*(1-app.autosufProd) 1 app.annuelConsoProd*(1-app.autosufProd)  app.annuelConsoProd*(1-app.autosufProd)  app.annuelConsoProd*(1-app.autosufProd)  app.annuelConsoProd*(1-app.autosufProd)  0 1 0 0]);
            matriceprix=matricetrans*app.tabPrix;
            matriceprix(1)=[];
            matriceprix(1)=(app.tabPrix(2,1)*(1-app.autosufProd)*app.annuelConsoProd)-(app.tabPrix(1,1)*(app.prodAnnuel*(1-app.autoconsoProd)));
            results=matriceprix;
            end
            end
            end    
        end
        function [Autosuffi,autoconsom,consoresidquart,Surplusquart,Prodtotal,Socquart] = Dimdata(~,PIeol,PIsol,PIbio,CAind,CApart,PIbat,Capabat)
                load('DataCE.mat','ComsoPart','ComsoUsine','LoadFactorCo','LoadFactorEol','LoadFactorSol');
                Comsopartquart = CApart .*ComsoPart;
                Comsousinequart = CAind .*ComsoUsine;
                Prodeolquart = (PIeol/4).*LoadFactorEol;
                Prodsolquart = (PIsol/4).*LoadFactorSol;
                Prodbioquart = (PIbio/4).*LoadFactorCo;
                Prodtotalquart = Prodsolquart+Prodeolquart+Prodbioquart;
                Prodtotal =  sum(Prodtotalquart);
                Consototalquart = Comsopartquart+Comsousinequart;
                Consototal = sum(Consototalquart);
                Surplusquart = Prodtotalquart-Consototalquart;
                Surplusquart(Surplusquart<0)=0;
                consoresidquart=Consototalquart-Prodtotalquart;
                consoresidquart(consoresidquart<0)=0;
                Socquart =zeros(35136,1);
                %Effet batterie
                if (PIbat==0 || Capabat==0) % pas de batterie
                % Socquart =zeros(35136,1);
                else
                    %on calcule la charge possible en fonction de la puissance de la batterie
                    CapachargMax=PIbat/4;
                    % on initialise la batterie a zero le 1er janvier a 00:00
                    Soccharge=0;
                
                for i=1:35136 
                    %test si charge ou decharge
                 if Surplusquart(i)>0 %il y a du surplus
                   if (Surplusquart(i) <= CapachargMax) % verifie si on peut charger tout le surplus dans la battterie.
                    
                       %verifie que la batterie n'est pas pleine
                   if (Soccharge + Surplusquart(i) < Capabat) %La batterie n'est pas pleine 
                      Soccharge=Soccharge + Surplusquart(i); %on charge la batterie
                      Socquart(i)=Soccharge;
                      Surplusquart(i)=0; %on a tout mis dans la batterie
                   else
                     Surplusquart(i)=Surplusquart(i)-(Capabat-Soccharge); % il reste du surplus
                     Soccharge=Capabat;%batterie Full.
                     Socquart(i)=Soccharge; %batterie Full.
                   end
                   else
                       if (Soccharge + CapachargMax < Capabat) %La batterie n'est pas pleine 
                      Soccharge=Soccharge + CapachargMax; %on charge la batterie
                      Socquart(i)=Soccharge;
                      Surplusquart(i)=Surplusquart(i)-CapachargMax; %on a tout  pas tout mis dans la batterie
                       else
                     Surplusquart(i)=Surplusquart(i)-(Capabat-Soccharge); % il reste du surplus
                     Soccharge=Capabat;%batterie Full.
                     Socquart(i)=Soccharge; %batterie Full
                       end
                   end
                 else
                   if consoresidquart(i) > 0 && Soccharge >0 %il y a de la demande et la batterie n est pas vide
                     if (consoresidquart(i) <= CapachargMax) % verifie si on peut decharger tout le conso de la battterie.
                       %verifie que la batterie n'est pas vide avant decharge complete
                      if (Soccharge - consoresidquart(i) >= 0) %La batterie n'est pas survide 
                      Soccharge=Soccharge - consoresidquart(i); %on decharge la batterie
                      Socquart(i)=Soccharge;
                      consoresidquart(i)=0; %on a tout pris dans la batterie
                      else
                      consoresidquart(i)=consoresidquart(i)-Soccharge; % il reste de la conso
                      Soccharge=0;%batterie vide.
                      Socquart(i)=Soccharge; %batterie Vide.
                      end
                     else
                      if (Soccharge - CapachargMax >=0 ) %La batterie n'est pas survide
                      Soccharge=Soccharge - CapachargMax; %on decharge la batterie
                      Socquart(i)=Soccharge;
                      consoresidquart(i)=consoresidquart(i)-CapachargMax; %on a pris le max dans la batterie
                      else
                      consoresidquart(i)=consoresidquart(i)-Soccharge; % il reste de la conso
                      Soccharge=0;%batterie vide.
                      Socquart(i)=Soccharge; %batterie vide
                      end
                     end
                   else
                       Socquart(i)=Soccharge;%batterie reste dans sont etat de charge.
                   end
                
                
                 end
                end
                end
                Suplustotal = sum(Surplusquart);
                Consototalresid=sum(consoresidquart);
                Autosuffi  = (Consototal-Consototalresid)/Consototal;
                autoconsom = (Prodtotal-Suplustotal)/Prodtotal;

        end
        
        function  CEini(app)
            app.NbrMembre=size(app.CA,2);
            app.VMembre= 1:app.NbrMembre;
            app.autosuf=1:app.NbrMembre;
            app.autocons=1:app.NbrMembre;
            app.autosuffiF=1:app.NbrMembre;
            app.autoconsomF=1:app.NbrMembre;
            app.autoSuffAcquise=1:app.NbrMembre;
            app.Prod=1:app.NbrMembre;
            app.Resid=zeros(35136,app.NbrMembre);
            app.Su=zeros(35136,app.NbrMembre);
            app.Residf=zeros(35136,app.NbrMembre);
            app.Suf=zeros(35136,app.NbrMembre);
            app.SocQuart=zeros(35136,app.NbrMembre);
            for i=1:1:app.NbrMembre
                [app.autosuf(i),app.autocons(i),app.Resid(:,i),app.Su(:,i),app.Prod(i),app.SocQuart(:,i)]= Dimdata(app,app.PIEol(i),app.PISol(i),app.PIBio(i),app.CAInd(i),app.CA(i),app.PIBat(i),app.CapaBat(i));
            end
            Sutotquart=zeros(35136,1);
            Residtotquart=zeros(35136,1);
            for i=1:35136
                Sutotquart(i,1)=sum (app.Su(i,:));
                Residtotquart(i,1)=sum (app.Resid(i,:));
                if Residtotquart(i,1)==0
                    for j=1:app.NbrMembre
                        app.Suf(i,j)=app.Su(i,j);
                        app.Residf(i,j)=0;
                    end
                end
                if Residtotquart(i,1)>=Sutotquart(i,1)

                    a=Sutotquart(i,1)/Residtotquart(i,1);
                    for j=1:app.NbrMembre
                        if app.Resid(i,j)~=0
                            app.Residf(i,j)=app.Resid(i,j)-(a*(app.Resid(i,j)));
                        else
                            app.Residf(i,j)=0;
                        end
                        app.Suf(i,j)=0;
                    end
                end
                if Residtotquart(i,1) < Sutotquart(i,1)
                    b=(Sutotquart(i,1)-Residtotquart(i,1))/Sutotquart(i,1);
                    for j=1:app.NbrMembre
                        if app.Su(i,j)~=0
                            app.Suf(i,j)=(b*app.Su(i,j));
                        else
                            app.Suf(i,j)=0;
                        end
                        app.Residf(i,j)=0;
                    end
                end
            end
            for i=1:app.NbrMembre
                app.autosuffiF(i)  = ((app.CA(i)+app.CAInd(i))-sum(app.Residf(:,i)))/(app.CA(i)+app.CAInd(i));
                app.autoconsomF(i) = (app.Prod(i)-sum(app.Suf(:,i)))/app.Prod(i);
                app.autoSuffAcquise(i)=(sum(app.Resid(:,i))-sum(app.Residf(:,i)))/(app.CA(i)+app.CAInd(i));
            end
            app.SurplusvenduCE= app.autoconsomF-app.autocons;
            app.SurplusvenduCE= fillmissing(app.SurplusvenduCE,'constant',0);
            app.autosuffiF= fillmissing(app.autosuffiF,'constant',0);
            app.autoSuffAcquise= fillmissing(app.autoSuffAcquise,'constant',0);
            app.autocons=fillmissing(app.autocons,'constant',0);
            app.autosuf=fillmissing(app.autosuf,'constant',0);
        end
        
        function updateInfoCE(app)
            app.NombredemembreEditField.Value=sprintf('%0.f %',size(app.CEPerso.Nom,1));
            app.ConsommationTotaleEditField.Value=sprintf('%0.f kWh%',sum(app.CEPerso.Consommationannuelleparticulier) + sum(app.CEPerso.Consommationannuelleusine));
            app.ProductionTotaleEditField.Value=sprintf('%0.f kWh%',sum(app.CEPerso.PuissanceinstalleeBio)*6547.4+sum(app.CEPerso.PuissanceinstalleeSol)*937.9+sum(app.CEPerso.PuissanceinstalleeEol)*2076.9);
            if  (max(app.CEPerso.PuissanceinstalleeBio) + max(app.CEPerso.PuissanceinstalleeSol)+max(app.CEPerso.PuissanceinstalleeEol) == 0)
            app.ProductionTotaleEditField.Value=sprintf('%0.f kWh%',0);
            app.consoCEmensuel=[];
            bar(app.UIAxesCE,["jan" "fev" "mars" "avril" "mai" "juin" "juil" "aout" "sept" "oct" "nov" "dec" ],[zeros(12,1)],0.4,'stacked');
            legend(app.UIAxesCE,'off');
            app.VolumeTotalEchangCEEditField.Value=sprintf('%0.f kWh%',0);
            return
            end
            if  size(app.CEPerso,1) == 0
            app.VolumeTotalEchangCEEditField.Value=sprintf('%0.f kWh%',0);
            app.ProductionTotaleEditField.Value=sprintf('%0.f kWh%',0);
            app.consoCEmensuel=[];
            app.UITableCE.Data=app.CEPerso;
            app.UITable3.Data=[];
            bar(app.UIAxesCE,["jan" "fev" "mars" "avril" "mai" "juin" "juil" "aout" "sept" "oct" "nov" "dec" ],[zeros(12,1)],0.4,'stacked');
            legend(app.UIAxesCE,'off');
            return
            else
             app.PIBio=(app.CEPerso{:,'PuissanceinstalleeBio'})';
             app.PIEol=(app.CEPerso{:,'PuissanceinstalleeEol'})';
             app.PISol=(app.CEPerso{:,'PuissanceinstalleeSol'})';
             app.CA=(app.CEPerso{:,'Consommationannuelleparticulier'})';
             app.CAInd=(app.CEPerso{:,'Consommationannuelleusine'})'; 
             app.PIBat=(app.CEPerso{:,'PuissanceinstalleeBatt'})';
             app.CapaBat=(app.CEPerso{:,'Capabat'})';

             CEini(app)
             app.tabPrix = get(app.tariffourisseur,'Data');
             GAgCE=(app.autoSuffAcquise.*(app.CA+app.CAInd).*(app.tabPrix(2,1)-app.PrixventedellectricitdelaCEauconsommateur.Value))';
             GVgCE=(app.SurplusvenduCE.*((app.PrixdachatdellectricitdelaCEauproducteur.Value-app.tabPrix(1,1)).*app.Prod))';
             app.UITable3.Data=array2table([app.CEPerso.Nom compose('%0.2f%%',(app.autosuf.*100)') compose('%0.2f%%',(app.autocons.*100)') compose("%0.0f kWh (%0.2f%%) %0.0f €",[(app.autoSuffAcquise.*(app.CA+app.CAInd))' (app.autoSuffAcquise.*100)' GAgCE]) compose("%0.0f kWh (%0.2f%%) %0.0f €",[(app.SurplusvenduCE.*app.Prod)' (app.SurplusvenduCE.*100)' GVgCE]) compose("%0.0f €", (GAgCE+GVgCE)-app.tabPrix(13,1)) ],"VariableNames",{'Nom','Autosufficance','Autoconsomation','Achat CE','Surplus Vendu CE', 'Gain grace CE'});
             colororder(app.UIAxesCE,"gem12");
             %calcule de l energie echater dans la CE mensuel par menbre.
             app.consoCEmensuel=[];
             for j=1:app.NbrMembre
             for i=1:12 %pour chacque mois.
             app.consoCEmensuel(i,j)=(sum(app.Resid(((i-1)*35136/12)+1:(i*35136/12),j))-sum(app.Residf(((i-1)*35136/12)+1:(i*35136/12),j)));
             end
             end
             app.VolumeTotalEchangCEEditField.Value=sprintf('%0.f kWh%',(sum(app.Resid,"all")-sum(app.Residf,"all")));
             bar(app.UIAxesCE,["jan" "fev" "mars" "avril" "mai" "juin" "juil" "aout" "sept" "oct" "nov" "dec" ],[app.consoCEmensuel],0.4,'stacked');
             legend(app.UIAxesCE,(app.CEPerso.Nom)','Location','best');
            end
        end
       end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.CEVideStop =false;
            app.CEPerso=table('Size',[0 8],'VariableNames',{'Nom','Consommationannuelleparticulier','Consommationannuelleusine','PuissanceinstalleeSol','PuissanceinstalleeEol','PuissanceinstalleeBio','PuissanceinstalleeBatt','Capabat'},'VariableTypes',{'string','double','double','double','double','double','double','double'});
            app.consoCEmensuel=zeros(0,12);
            app.Pathexe=showpath(app);
            app.pathTarif=fullfile(app.Pathexe,"tableautarif.csv");
            app.PIBio=0;
            app.PIEol=0;
            app.PISol=app.Puissancetotaleinstalle.Value;
            app.CAInd=0;
            app.CA=app.Consommationannuelledunproducteur.Value;
            app.PIBat=0;
            app.CapaBat=0;
            %Import du fichier de dimensionement solaire par defaut 
            % import du fichier tarif fourisseur.
            importtarif(app, app.pathTarif, [2, Inf]);
            app.tariffourisseur.RowName = {'Prix injection €/kWh'; 'Prix énergie €/kWh';'Frais de distribution Resa €/kWh';'Frais de transport Resa €/kWh';'Frais de distribution terme fixe Resa (€/an)';'Redevance de raccordement €/kWh ';'Accise spéciale €/kWh';'Cotisation sur l''énergie €/kWh';'Cotisation Verte (€/an)';'Tarif Prosumer Resa (€/an/kVA)';'Redevance fixe (€/an)';'Forfait panneaux solaires (€/kVA par mois)';'Forfait partage d''énergie (€/an)'};
            app.tariffourisseur.Data=app.tabPrixFour.Totalenergie;
            app.Fournisseur.Text='Total Energie';
            app.Date.Text=app.tabPrixFour.Date(1);
            app.tabPrix = get(app.tariffourisseur,'Data');
            app.ChoixGraphique.Enable=false;
            app.nbrprod = app.Nombredeproducteur.Value;
            app.Puissanceprod =app.Puissancetotaleinstalle.Value/app.nbrprod;
            app.prodAnnuel=app.Puissanceprod* 937.9;
            app.Affprodan.Text=sprintf('%0.f kWh%',app.prodAnnuel);
            movegui(app.DimCEUIFigure,"center"); 
            app.DimCEUIFigure.Name = 'DimCE  1.2';
            app.ExporterlesdonnesdoptimisationMenu.Enable="off";
            app.ExporterlesdonnesdelaCEperso.Enable='off';
            app.DimCEUIFigure.WindowState= 'normal';
            delete(app.DimensionnementinstallationetbatterieTab);
            app.DimCEUIFigure.Visible = 'on';           
            drawnow;
        end

        % Callback function: QuitterButton, QuitterMenu
        function QuitterButtonPushed(app, event)
            quit
        end

        % Callback function: LancerloptimisationButton, 
        % ...and 1 other component
        function LancerloptimisationButtonPushed(app, event)
        app.TabGroup.SelectedTab=app.SimulationCETab;
        selection = uiconfirm(app.DimCEUIFigure,"Voulez vous lancer l'optimisation ?",'Confirmation');
        switch selection
        case 'OK'
            
            ChoixtypedesimulationButtonGroupSelectionChanged(app);
            if app.CEVideStop
                return;
            end
            app.tabOpti=[];
            app.tabOptiNum=[];
            app.iCount=1;
            app.VOpti =0;
            app.Pas=1;
            app.NbrConsoMax=app.Nombremaximundeconsommateur.Value;
            app.optiFound=false;
            app.annuelConsoConso=app.Consommationannuelledunconsommateur.Value;
            app.nbrConsoSurplusMax=1;
            app.surplusMax = 0;
            app.autosufMax = 0;
            app.prixVente=app.PrixventedellectricitdelaCEauconsommateur.Value;
            app.prixAchat=app.PrixdachatdellectricitdelaCEauproducteur.Value;
            app.CEini();
            % %on choisi le plus gros vendeur  pour l affichage
            % [~,I]=max(app.SurplusvenduCE*app.Prod');     
            % app.annuelConsoProd = app.CA(1,I)+app.CAInd(1,I);
            % app.autosufInterProd=app.autoSuffAcquise(I);
            % if app.annuelConsoProd>0
            % app.autosufProd=app.autosuffiF(I);
            % app.autoconsoProd=app.autoconsomF(I);
            % else
            % app.autoconsoProd=0;
            % app.autosufProd=0;
            % end
            %lancer l'optimisation 
            optiCe(app);
            app.ExporterlesdonnesdoptimisationMenu.Enable="on";
            case 'Annuler'
          return
        end


        end

        % Value changed function: ChoixduFournisseurDropDown
        function ChoixduFournisseurDropDownValueChanged(app, event)
            value = app.ChoixduFournisseurDropDown.Value;
              switch (value)
                case 'Total Energie'
                app.tariffourisseur.Data=app.tabPrixFour.Totalenergie;
                app.Fournisseur.Text='Total Energie';
                app.Date.Text=app.tabPrixFour.Date(1);
                case 'Luminus'
                app.tariffourisseur.Data=app.tabPrixFour.Luminus;
                 app.Fournisseur.Text='Luminus';
                 app.Date.Text=app.tabPrixFour.Date(1);
                case 'Engie'
                app.tariffourisseur.Data=app.tabPrixFour.Engie;
                app.Fournisseur.Text='Engie';
                app.Date.Text=app.tabPrixFour.Date(1);
                case 'Mega'
                app.tariffourisseur.Data=app.tabPrixFour.Mega;
                 app.Fournisseur.Text='Mega';
                 app.Date.Text=app.tabPrixFour.Date(1);
                  case 'Bolt'
                app.tariffourisseur.Data=app.tabPrixFour.Bolt;
                 app.Fournisseur.Text='Bolt';
                 app.Date.Text=app.tabPrixFour.Date(1);
                  case 'Eneco'
                app.tariffourisseur.Data=app.tabPrixFour.Eneco;
                 app.Fournisseur.Text='Eneco';
                 app.Date.Text=app.tabPrixFour.Date(1);
                 case 'Luminus BXL'
                app.tariffourisseur.Data=app.tabPrixFour.LuminusBXL;
                 app.Fournisseur.Text='Luminus BXL';
                 app.Date.Text=app.tabPrixFour.Date(1);
                 case 'Total BXL'
                app.tariffourisseur.Data=app.tabPrixFour.TotalBXL;
                 app.Fournisseur.Text='Total BXL';
                 app.Date.Text=app.tabPrixFour.Date(1);
              end
              app.tabPrix = get(app.tariffourisseur,'Data');
            drawnow;
        end

        % Value changed function: ChoixGraphique
        function ChoixGraphiqueValueChanged(app, event)
            value = app.ChoixGraphique.Value;
            colororder(app.UIAxes,"gem12");
              switch (value)
                  case 'Graphique du surplus et de autosuffisance'
                   app.UIAxes.XLabel.String="Nombre de consomateur (en Plus)";   
                   app.UIAxes.YLabel.String='%';
                   plot(app.UIAxes,app.tabOptiNum(:,1),app.tabOptiNum(:,2),app.tabOptiNum(:,1),app.tabOptiNum(:,3));
                   legend(app.UIAxes,{'Autosuffisance conso.' 'Surplus Vendu CE'},'Location','best'); 
                  case 'Graphique du Gain des Producteurs et des Consommateurs'
                   app.UIAxes.XLabel.String="Nombre de consomateur (en Plus)";
                   app.UIAxes.YLabel.String='€';
                   plot(app.UIAxes,app.tabOptiNum(:,1),app.tabOptiNum(:,4),app.tabOptiNum(:,1),app.tabOptiNum(:,5));
                   legend(app.UIAxes,{'Gain Consommateur' 'Gain Producteur'},'Location','best');
                  case 'Graphique comparer Facture Consommateur à l’optimum'
                   app.UIAxes.YLabel.String='€';
                   app.UIAxes.XLabel.String="";
                   app.tabPrixConso = fliplr(caluclePrix(app,false,true).');  
                   app.tabPrixConsoCE =fliplr(caluclePrix(app,true,true).');
                   bar(app.UIAxes,[ "Sans CE" "Avec CE"],[app.tabPrixConso ; app.tabPrixConsoCE],0.4,'stacked');
                   legend(app.UIAxes,fliplr([	"Prix énergie"	"Frais de distribution Resa"	"Frais de transport Resa"	"Frais de distribution terme fixe Resa"	"Redevance de raccordement"	"Accise spéciale"	"Cotisation sur l''énergie"	"Cotisation Verte "	"Tarif Prosumer Resa"	"Redevance fixe "	"Forfait panneaux solaires "	"Forfait partage d''énergie "]),'Location','bestoutside');
                  case 'Graphique comparer Facture Producteur à l’optimum'
                  app.UIAxes.YLabel.String='€'; 
                  app.UIAxes.XLabel.String="";
                  app.tabPrixProd = fliplr(caluclePrix(app,false,false).');  
                  app.tabPrixProdCE =fliplr(caluclePrix(app,true,false).');
                  bar(app.UIAxes,[ "Sans CE" "Avec CE"],[app.tabPrixProd ; app.tabPrixProdCE],0.4,'stacked');
                  legend(app.UIAxes,fliplr(["Prix énergie"	"Frais de distribution Resa"	"Frais de transport Resa"	"Frais de distribution terme fixe Resa"	"Redevance de raccordement"	"Accise spéciale"	"Cotisation sur l''énergie"	"Cotisation Verte "	"Tarif Prosumer Resa"	"Redevance fixe"	"Forfait panneaux solaires "	"Forfait partage d''énergie "]),'Location','bestoutside');
              end
              
            drawnow;
        end

        % Callback function: ConfigurationpardfaultButton, 
        % ...and 1 other component
        function ConfigurationpardfaultButtonPushed(app, event)

            app.ChoixduFournisseurDropDown.Value="Total Energie";
            app.iCount=1;
            app.tabOpti=[];
            app.tabOptiNum=[];
            app.ChoixGraphique.Value="Graphique du surplus et de autosuffisance";
            app.ChoixGraphique.Enable=false;
            app.PrixdachatdellectricitdelaCEauproducteur.Value=0.08;
            app.PrixventedellectricitdelaCEauconsommateur.Value=0.09;
            app.Consommationannuelledunconsommateur.Value=3500;
            app.Nombremaximundeconsommateur.Value=10;
            app.Consommationannuelledunproducteur.Value=3500;
            app.Nombredeproducteur.Value=1;
            app.Puissancetotaleinstalle.Value=4;
            app.TypedeproductionDropDown.Value="Solaire";
            app.prodAnnuel=app.Puissanceprod* 937.9;
            app.nbrprod = app.Nombredeproducteur.Value;
            app.Puissanceprod =app.Puissancetotaleinstalle.Value/app.nbrprod;
            app.Affprodan.Text=sprintf('%0.f kWh%',app.prodAnnuel);
            app.GainparproducteursitoutelnergieensurplusvenduedanslaCE.Value="?";
            app.GainparconsoAuMax.Value="?";
            app.PartmaximumdelnergietotaleproduitevenduelaCE.Value="?";
            app.NombreconsoAuMAX.Value=0; 
            app.NombredeConsommateuroptimum.Value=0; 
            app.Gainparproducteurloptimum.Value="?";
            app.Gainparconsommateurloptimum.Value="?";
            delete(app.UIAxes.Children);
            lgd = findobj('type', 'legend');
            delete(lgd);
            app.UITable.Data=[];
            app.ExporterlesdonnesdoptimisationMenu.Enable="off";
            app.PIBio=0;
            app.PIEol=0;
            app.PISol=app.Puissancetotaleinstalle.Value;
            app.CAInd=0;
            app.CA=app.Consommationannuelledunproducteur.Value;
            app.PIBat=0;
            app.CapaBat=0;
            app.ChoixtypedesimulationButtonGroup.SelectedObject=app.SimulationrapideButton;
            app.ChoixtypedesimulationButtonGroupSelectionChanged(app);
            drawnow;
            

        end

        % Menu selected function: ExporterlesdonnesdelaCEperso, 
        % ...and 1 other component
        function ExporterlesdonnesdoptimisationMenuSelected(app, event)
             
             [f, p] = uiputfile('*.xls');
             if(f == 0)
                 focus(app.DimCEUIFigure);
                 return
             end
            fullsavepath=fullfile(p,f);
            switch event.Source.Text
                case "Exporter les données d'optimisation"
                writetable(array2table(app.tabOptiNum,'VariableNames',{'Nbr de Conso.','Autosuffisance conso.','Surplus Vendu CE','Gain conso.','Gain prod.'}),fullsavepath);
                case "Exporter les informations de la CE Personnalisée"
                writetable(app.UITable3.Data,fullsavepath);
            end
            
            focus(app.DimCEUIFigure);
               return
            
        end

        % Value changed function: Puissancetotaleinstalle, 
        % ...and 1 other component
        function TypedeproductionDropDownValueChanged(app, event)
            value = app.TypedeproductionDropDown.Value; 
            app.nbrprod = app.Nombredeproducteur.Value;
            app.Puissanceprod =app.Puissancetotaleinstalle.Value/app.nbrprod;
            switch (value) 
                case  'Solaire'
                app.PIBio=0;
                app.PIEol=0;
                app.PISol=app.Puissancetotaleinstalle.Value;
                app.prodAnnuel=app.Puissanceprod* 937.9;
                app.prodTotalCE=app.Puissancetotaleinstalle.Value* 937.9;
                case  'Eolien'  
                app.PIBio=0;
                app.PIEol=app.Puissancetotaleinstalle.Value;
                app.PISol=0;   
                app.prodAnnuel=app.Puissanceprod* 2076.9;
                app.prodTotalCE=app.Puissancetotaleinstalle.Value*2076.9;
                case  'Cogen/Biometh.'  
                app.PIBio=app.Puissancetotaleinstalle.Value;
                app.PIEol=0;
                app.PISol=0;
                app.prodAnnuel=app.Puissanceprod* 6547.4;
                app.prodTotalCE=app.Puissancetotaleinstalle.Value* 6547.4;
            end
            app.Affprodan.Text=sprintf('%0.f kWh%',app.prodAnnuel*app.nbrprod);
            
        end

        % Menu selected function: InterfaceMenu
        function InterfaceMenuSelected(app, event)
            
           childhandles=allchild(app.DimCEUIFigure);
            set( childhandles, 'Visible', 'off' );
            ax=axes(app.DimCEUIFigure,'Units','Normalize','Position',[0 0 1 1]);
            ax.DataAspectRatioMode="manual";
            uistack(ax, 'top');
            %movegui(fig,'center');
            im = blockedImage('aide1.png');
            bigimageshow(im,Parent=ax);
            axis(ax,'fill','off');
            ax.Toolbar.Visible = 'off';
            drawnow;
            if(~waitforbuttonpress)
            im = blockedImage('aide.png');
            bigimageshow(im,Parent=ax);
            axis(ax,'fill','off');
            ax.Toolbar.Visible = 'off';
            drawnow;
            end
            if(~waitforbuttonpress)
            delete(ax);
            set( childhandles, 'Visible', 'on' );
            end
        end

        % Menu selected function: AproposdeDimCEMenu
        function AproposdeDimCEMenuSelected(app, event)
        
                        uialert(app.DimCEUIFigure,["\fontsize{10}  DimCE est un logiciel de dimensionnement, qui permet de connaître le nombre optimal et le nombre maximal de membres consommateurs d'une communauté d'énergie";'   ';"\fontsize{10}   Pour toute question ou information, veuillez nous contacter via l''adresse e-mail suivante : \color{blue}dimce@ikmail.com"],"A propos de DimCE","Icon","info", 'interpreter','tex');
        end

        % Button pushed function: Buttonajouter
        function ButtonajouterPushed(app, event)
            app.Buttonajouter.Enable="off";
            drawnow;
            app.CEPerso = [app.CEPerso;{app.Nom.Value,app.Consommationannuelleparti.Value,app.Consommationannuelleindust.Value,app.PuissanceinstalleSol.Value,app.PuissanceinstalleEol.Value,app.PuissanceinstalleBio.Value,app.Puissancebat.Value,app.Capacitebat.Value}];
            app.UITableCE.Data=app.CEPerso;
            updateInfoCE(app);
            drawnow;
            app.Buttonajouter.Enable="on";
            app.Consommationannuelleindust.Value=0;
            app.Nom.Value="";
            app.Consommationannuelleparti.Value=3500;
            app.PuissanceinstalleBio.Value=0;
            app.PuissanceinstalleSol.Value=0;
            app.PuissanceinstalleEol.Value=0;
            app.Capacitebat.Value=0;
            app.Puissancebat.Value=0;

            drawnow;
            app.ExporterlesdonnesdelaCEperso.Enable="on";


        end

        % Selection changed function: ChoixtypedesimulationButtonGroup
        function ChoixtypedesimulationButtonGroupSelectionChanged(app, event)
            selectedButton = app.ChoixtypedesimulationButtonGroup.SelectedObject;
            switch (selectedButton.Text) 
                case  'Simulation rapide'  
                app.Puissancetotaleinstalle.Enable="on";
                app.TypedeproductionDropDown.Enable="on";
                app.Nombredeproducteur.Enable="on";
                app.Consommationannuelledunproducteur.Enable="on";
                app.nbrprod = app.Nombredeproducteur.Value;
                app.CA=[app.Consommationannuelledunproducteur.Value];
                app.CAInd=0;
                app.PIBat=0;
                app.CapaBat=0;
                TypedeproductionDropDownValueChanged(app);
                app.UITable.ColumnName(5)={"Gain prod."};
                case  'Simulation sur CE personnalisée'     
                app.Puissancetotaleinstalle.Enable="off";
                app.TypedeproductionDropDown.Enable="off";
                app.Nombredeproducteur.Enable="off";
                app.Consommationannuelledunproducteur.Enable="off";
                app.UITable.ColumnName(5)={"Gain prod.principal"};
                if size(app.CEPerso,1)==0 || max(app.CEPerso.PuissanceinstalleeBio) + max(app.CEPerso.PuissanceinstalleeSol)+max(app.CEPerso.PuissanceinstalleeEol) ==0 
                app.TabGroup.SelectedTab=app.PersonnaliserlaCETab;
                uialert(app.DimCEUIFigure,"CE Personnalisée Vide ou sans producteur, Veuillez ajouter au moins un membre producteur","CE Vide ou sans Producteur");
                app.CEVideStop=true;
                app.Affprodan.Text=sprintf('%0.f kWh%',0);
                
                return;
                end
                app.CEVideStop=false;
                app.PIBio=(app.CEPerso{:,'PuissanceinstalleeBio'})';
                app.PIEol=(app.CEPerso{:,'PuissanceinstalleeEol'})';
                app.PISol=(app.CEPerso{:,'PuissanceinstalleeSol'})';
                app.CA=(app.CEPerso{:,'Consommationannuelleparticulier'})';
                app.CAInd=(app.CEPerso{:,'Consommationannuelleusine'})';
                app.PIBat=(app.CEPerso{:,'PuissanceinstalleeBatt'})';
                app.CapaBat=(app.CEPerso{:,'Capabat'})';
                app.CEini();
                app.Affprodan.Text=sprintf('%0.f kWh%',sum(app.Prod));

            end
            
        end

        % Button pushed function: ButtonEffacer
        function ButtonEffacerPushed(app, event)
        msgSel = uiconfirm(app.DimCEUIFigure,"Voulez-vous supprimer le membre sélectionné ?",'Confirmation');
        switch msgSel
        case 'OK'
        app.CEPerso(app.UITableCE.Selection,:)=[];
        app.UITableCE.Data=app.CEPerso;
        updateInfoCE(app);
        if (size(app.CEPerso,1)==0)
            app.ExporterlesdonnesdelaCEperso.Enable="off";
        end
        case 'Annuler'
          return

        end

        end

        % Cell edit callback: UITableCE
        function UITableCECellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            if(isstring(newData))
            elseif (isnan(newData))
            newData = 0;
            app.UITableCE.Data.(indices(2))(indices(1))=newData;
            end
            app.CEPerso.(indices(2))(indices(1))=newData;
            updateInfoCE(app);
        end

        % Button pushed function: ButtonSupprimer
        function ButtonSupprimerPushed(app, event)
           selection = uiconfirm(app.DimCEUIFigure,"Voulez-vous supprimer cette CE ?",'Confirmation');
        switch selection
        case 'OK'
        app.CEPerso(:,:)=[];
        app.UITableCE.Data=app.CEPerso;
        app.consoCEmensuel(:,:)=[];
        updateInfoCE(app);
        app.ExporterlesdonnesdelaCEperso.Enable="off";
        case 'Annuler'
          return
        end
 
        end

        % Button pushed function: ButtonExporter
        function ButtonExporterPushed(app, event)
           [f, p] = uiputfile('*.xls','Choisissez un emplacement et un nom pour le fichier CE ...');
           if(f == 0)
                 focus(app.DimCEUIFigure);
                 return
           end
            fullsavepath=fullfile(p,f);
            writetable(app.CEPerso,fullsavepath);
            focus(app.DimCEUIFigure);
               return  
        end

        % Button pushed function: ButtonImporter
        function ButtonImporterPushed(app, event)
             [f, p] = uigetfile('*.xls','Choisissez un fichier CE ...');
             if(f == 0)
                 focus(app.DimCEUIFigure);
                 return
             end
            app.CEPerso(:,:)=[];
            app.UITableCE.Data=app.CEPerso;
            app.consoCEmensuel(:,:)=[];
            app.ExporterlesdonnesdelaCEperso.Enable="off";
            fullsavepath=fullfile(p,f);
            focus(app.DimCEUIFigure);
            opts = detectImportOptions(fullsavepath);
            opts.VariableTypes = ["string", "double", "double", "double", "double","double","double","double"];
            app.CEPerso=readtable(fullsavepath,opts);
            app.UITableCE.Data=app.CEPerso;
            updateInfoCE(app);
            app.ExporterlesdonnesdelaCEperso.Enable="on";
            
               return  
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            selectedTab = app.TabGroup.SelectedTab;
            if selectedTab== app.PersonnaliserlaCETab
            updateInfoCE(app);
            end

        end

        % Value changed function: ConsommationannuelleDim, 
        % ...and 1 other component
        function TypedeproductionDropDown_2ValueChanged(app, event)
            value = app.TypedeproductionDropDown_2.Value;
            Prodinst=(0.01*app.ConsommationannuelleDim.Value):(0.01*app.ConsommationannuelleDim.Value):(2*app.ConsommationannuelleDim.Value);
            Vitessederetour=zeros(1,200);
            Prixdepart=3000;
            for Puiss=1:1:200
               switch (value) 
                case  'Solaire'
                puissanceinst=Prodinst./ 937.9 ;
                [AutosuffiDim,~,~,~,~,~] = Dimdata(app,0,Prodinst(Puiss)/937.9,0,0,app.ConsommationannuelleDim.Value,0,0);
                CoutaukWatt=1000;
                case  'Eolien' 
                puissanceinst=Prodinst./ 2076.9;
                [AutosuffiDim,~,~,~,~,~] = Dimdata(app,Prodinst(Puiss)/2076.9,0,0,0,app.ConsommationannuelleDim.Value,0,0);
                CoutaukWatt=1000;
                case  'Cogen/Biometh.' 
                puissanceinst=Prodinst./ 6547.4;
                [AutosuffiDim,~,~,~,~,~] = Dimdata(app,0,0,Prodinst(Puiss)/6547.4,0,app.ConsommationannuelleDim.Value,0,0);
                CoutaukWatt=1000;
               end
               app.tabPrix = get(app.tariffourisseur,'Data');
               %facture sans installation
                matricetrans=diag([0 1 app.ConsommationannuelleDim.Value app.ConsommationannuelleDim.Value 1 app.ConsommationannuelleDim.Value app.ConsommationannuelleDim.Value app.ConsommationannuelleDim.Value app.ConsommationannuelleDim.Value 0 1 0 0]);
                matriceprix=matricetrans*app.tabPrix;
                matriceprix(1)=[];
                matriceprix(1)=app.tabPrix(2,1).*app.ConsommationannuelleDim.Value;
                resultssans=sum(matriceprix,'all');
               %facture avec installation 

                matricetrans=diag([0 1 app.ConsommationannuelleDim.Value*(1-AutosuffiDim) app.ConsommationannuelleDim.Value*(1-AutosuffiDim) 1 app.ConsommationannuelleDim.Value*(1-AutosuffiDim) app.ConsommationannuelleDim.Value*(1-AutosuffiDim) app.ConsommationannuelleDim.Value*(1-AutosuffiDim) app.ConsommationannuelleDim.Value*(1-AutosuffiDim) 0 1 0 0]);
                matriceprix=matricetrans*app.tabPrix;
                matriceprix(1)=[];
                matriceprix(1)=app.tabPrix(2,1).*app.ConsommationannuelleDim.Value*(1-AutosuffiDim);
                resultsavec=sum(matriceprix,'all');
                Vitessederetour(Puiss)=(Prixdepart+(puissanceinst(Puiss)*CoutaukWatt))/(resultssans-resultsavec);
               
            end
            Puissanceproducteur =app.Puissancetotaleainstalle.Value;
            switch (value) 
                case  'Solaire'
                
                prodAnnuelDim=Puissanceproducteur* 937.9;
                case  'Eolien'  
                
                prodAnnuelDim=Puissanceproducteur* 2076.9;
                case  'Cogen/Biometh.'  
                  
                prodAnnuelDim=Puissanceproducteur* 6547.4;
            end
            plot(app.UIAxesdiminst,puissanceinst,Vitessederetour);
            app.Affprodan_2.Text=sprintf('%0.f kWh%',prodAnnuelDim);

        end

        % Cell edit callback: tariffourisseur
        function tariffourisseurCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            if(isstring(newData))
            newData = 0;
            elseif (isnan(newData))
            newData = 0;
            app.tariffourisseur.Data(indices(1),indices(2))=newData;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.DimCEUIFigure = uifigure('Visible', 'off');
            app.DimCEUIFigure.Name = 'DimCE';
            app.DimCEUIFigure.Icon = 'Carte 9 communes K2.png';
            app.FichierMenu = uimenu(app.DimCEUIFigure);
            app.FichierMenu.Text = 'Fichier';
            app.LancerloptimisationMenu = uimenu(app.FichierMenu);
            app.LancerloptimisationMenu.MenuSelectedFcn = createCallbackFcn(app, @LancerloptimisationButtonPushed, true);
            app.LancerloptimisationMenu.Accelerator = 'L';
            app.LancerloptimisationMenu.Text = 'Lancer l''optimisation';
            app.ConfigurationpardfaultMenu = uimenu(app.FichierMenu);
            app.ConfigurationpardfaultMenu.MenuSelectedFcn = createCallbackFcn(app, @ConfigurationpardfaultButtonPushed, true);
            app.ConfigurationpardfaultMenu.Accelerator = 'd';
            app.ConfigurationpardfaultMenu.Text = 'Configuration par défault';
            app.ExporterlesdonnesdoptimisationMenu = uimenu(app.FichierMenu);
            app.ExporterlesdonnesdoptimisationMenu.MenuSelectedFcn = createCallbackFcn(app, @ExporterlesdonnesdoptimisationMenuSelected, true);
            app.ExporterlesdonnesdoptimisationMenu.Accelerator = 'e';
            app.ExporterlesdonnesdoptimisationMenu.Text = 'Exporter les données d''optimisation';
            app.ExporterlesdonnesdoptimisationMenu.Tag = 'exportopti';
            app.ExporterlesdonnesdelaCEperso = uimenu(app.FichierMenu);
            app.ExporterlesdonnesdelaCEperso.MenuSelectedFcn = createCallbackFcn(app, @ExporterlesdonnesdoptimisationMenuSelected, true);
            app.ExporterlesdonnesdelaCEperso.Accelerator = 'F';
            app.ExporterlesdonnesdelaCEperso.Text = 'Exporter les informations de la CE Personnalisée';
            app.ExporterlesdonnesdelaCEperso.Tag = 'ExportCEP';
            app.QuitterMenu = uimenu(app.FichierMenu);
            app.QuitterMenu.MenuSelectedFcn = createCallbackFcn(app, @QuitterButtonPushed, true);
            app.QuitterMenu.Accelerator = 'q';
            app.QuitterMenu.Text = 'Quitter';
            app.AideMenu = uimenu(app.DimCEUIFigure);
            app.AideMenu.Text = 'Aide';
            app.InterfaceMenu = uimenu(app.AideMenu);
            app.InterfaceMenu.MenuSelectedFcn = createCallbackFcn(app, @InterfaceMenuSelected, true);
            app.InterfaceMenu.Accelerator = 'I';
            app.InterfaceMenu.Text = 'Interface';
            app.AproposdeDimCEMenu = uimenu(app.AideMenu);
            app.AproposdeDimCEMenu.MenuSelectedFcn = createCallbackFcn(app, @AproposdeDimCEMenuSelected, true);
            app.AproposdeDimCEMenu.Accelerator = 'A';
            app.AproposdeDimCEMenu.Text = 'A propos de DimCE';
            app.TabGroup = uitabgroup(app.DimCEUIFigure);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.SimulationCETab = uitab(app.TabGroup);
            app.SimulationCETab.Title = 'Simulation CE';
            app.DonnesconomiquesCEPanel = uipanel(app.SimulationCETab);
            app.DonnesconomiquesCEPanel.Title = 'Données économiques C.E.';
            app.PrixventedlectricitdelaCEauconsommateurLabel = uilabel(app.DonnesconomiquesCEPanel);
            app.PrixventedlectricitdelaCEauconsommateurLabel.Text = 'Prix vente de l''électricité de la CE au consommateur:';
            app.PrixventedellectricitdelaCEauconsommateur = uieditfield(app.DonnesconomiquesCEPanel, 'numeric');
            app.PrixventedellectricitdelaCEauconsommateur.Limits = [0 Inf];
            app.PrixventedellectricitdelaCEauconsommateur.Value = 0.09;
            app.PrixdachatdellectricitdelaCEauproducteurLabel = uilabel(app.DonnesconomiquesCEPanel);
            app.PrixdachatdellectricitdelaCEauproducteurLabel.Text = 'Prix d''achat de l''électricité de la CE au producteur:';
            app.PrixdachatdellectricitdelaCEauproducteur = uieditfield(app.DonnesconomiquesCEPanel, 'numeric');
            app.PrixdachatdellectricitdelaCEauproducteur.Limits = [0 Inf];
            app.PrixdachatdellectricitdelaCEauproducteur.Value = 0.08;
            app.kWhLabel_3 = uilabel(app.DonnesconomiquesCEPanel);
            app.kWhLabel_3.Text = ' €/kWh';
            app.kWhLabel_4 = uilabel(app.DonnesconomiquesCEPanel);
            app.kWhLabel_4.Text = ' €/kWh';
            app.ChoixduFournisseurDropDownLabel = uilabel(app.DonnesconomiquesCEPanel);
            app.ChoixduFournisseurDropDownLabel.Text = 'Choix du Fournisseur:';
            app.ChoixduFournisseurDropDown = uidropdown(app.DonnesconomiquesCEPanel);
            app.ChoixduFournisseurDropDown.Items = {'Total Energie', 'Luminus', 'Engie', 'Mega', 'Bolt', 'Eneco', 'Luminus BXL', 'Total BXL'};
            app.ChoixduFournisseurDropDown.ValueChangedFcn = createCallbackFcn(app, @ChoixduFournisseurDropDownValueChanged, true);
            app.ChoixduFournisseurDropDown.Value = 'Total Energie';
            app.LancerloptimisationButton = uibutton(app.DonnesconomiquesCEPanel, 'push');
            app.LancerloptimisationButton.ButtonPushedFcn = createCallbackFcn(app, @LancerloptimisationButtonPushed, true);
            app.LancerloptimisationButton.Tag = 'cmdOpti';
            app.LancerloptimisationButton.Text = 'Lancer l''optimisation';
            app.ConfigurationpardfaultButton = uibutton(app.DonnesconomiquesCEPanel, 'push');
            app.ConfigurationpardfaultButton.ButtonPushedFcn = createCallbackFcn(app, @ConfigurationpardfaultButtonPushed, true);
            app.ConfigurationpardfaultButton.Tag = 'cmdReset';
            app.ConfigurationpardfaultButton.Text = 'Configuration par défault';
            app.QuitterButton = uibutton(app.DonnesconomiquesCEPanel, 'push');
            app.QuitterButton.ButtonPushedFcn = createCallbackFcn(app, @QuitterButtonPushed, true);
            app.QuitterButton.Tag = 'cmdquit';
            app.QuitterButton.Text = 'Quitter';
            app.GraphiqueettablePanel = uipanel(app.SimulationCETab);
            app.GraphiqueettablePanel.Title = 'Graphique et table';
            app.UIAxes = uiaxes(app.GraphiqueettablePanel);
            xlabel(app.UIAxes, 'Nombre de consomateur (en Plus)')
            ylabel(app.UIAxes, '%')
            zlabel(app.UIAxes, 'Z')
            app.UITable = uitable(app.GraphiqueettablePanel);
            app.UITable.ColumnName = {'Nbr de Conso.'; 'Autosuffisance conso.'; 'Surplus Vendu CE'; 'Gain conso.'; 'Gain prod.'};
            app.UITable.RowName = {};
            app.UITable.SelectionType = 'row';
            app.UITable.Tag = 'LstDatabase';
            app.ChoixGraphique = uidropdown(app.GraphiqueettablePanel);
            app.ChoixGraphique.Items = {'Graphique du surplus et de autosuffisance', 'Graphique du Gain des Producteurs et des Consommateurs', 'Graphique comparer Facture Consommateur à l’optimum', 'Graphique comparer Facture Producteur à l’optimum'};
            app.ChoixGraphique.ValueChangedFcn = createCallbackFcn(app, @ChoixGraphiqueValueChanged, true);
            app.ChoixGraphique.Tag = 'cmbChoixGraph';
            app.ChoixGraphique.Value = 'Graphique du surplus et de autosuffisance';
            app.ValeursOptimalesPanel = uipanel(app.SimulationCETab);
            app.ValeursOptimalesPanel.Title = 'Valeurs Optimales';
            app.NombredeConsommateuroptimum1Label = uilabel(app.ValeursOptimalesPanel);
            app.NombredeConsommateuroptimum1Label.Text = 'Nombre de Consommateur optimum ';
            app.NombredeConsommateuroptimum = uieditfield(app.ValeursOptimalesPanel, 'numeric');
            app.NombredeConsommateuroptimum.Tag = 'nbrConsoOpti';
            app.GainparconsommateurloptimumEditFieldLabel = uilabel(app.ValeursOptimalesPanel);
            app.GainparconsommateurloptimumEditFieldLabel.Text = 'Gain par consommateur à l''optimum';
            app.Gainparconsommateurloptimum = uieditfield(app.ValeursOptimalesPanel, 'text');
            app.Gainparconsommateurloptimum.Value = '?';
            app.GainparproducteurloptimumEditFieldLabel_3 = uilabel(app.ValeursOptimalesPanel);
            app.GainparproducteurloptimumEditFieldLabel_3.Text = 'Gain par producteur à l''optimum';
            app.Gainparproducteurloptimum = uieditfield(app.ValeursOptimalesPanel, 'text');
            app.Gainparproducteurloptimum.Value = '?';
            app.ValeurspourintgralitdusurplusVenduPanel = uipanel(app.SimulationCETab);
            app.ValeurspourintgralitdusurplusVenduPanel.Title = 'Valeurs pour intégralité du surplus Vendu';
            app.GainparproducteurloptimumEditField_2Label = uilabel(app.ValeurspourintgralitdusurplusVenduPanel);
            app.GainparproducteurloptimumEditField_2Label.Text = {'Nombre de consommateur qui maximise'; ' la vente de l''énergie produite à la CE'};
            app.NombreconsoAuMAX = uieditfield(app.ValeurspourintgralitdusurplusVenduPanel, 'numeric');
            app.NombreconsoAuMAX.Tag = 'nbrConsoPartMax';
            app.PartmaximumdelnergietotaleproduitevenduelaCELabel = uilabel(app.ValeurspourintgralitdusurplusVenduPanel);
            app.PartmaximumdelnergietotaleproduitevenduelaCELabel.Text = {'Part maximum de l''énergie totale '; 'produite vendue à la CE'};
            app.PartmaximumdelnergietotaleproduitevenduelaCE = uieditfield(app.ValeurspourintgralitdusurplusVenduPanel, 'text');
            app.PartmaximumdelnergietotaleproduitevenduelaCE.Value = '?';
            app.GainparproducteurloptimumEditFieldLabel_2 = uilabel(app.ValeurspourintgralitdusurplusVenduPanel);
            app.GainparproducteurloptimumEditFieldLabel_2.Text = {'Gain par consommateur si toute l''énergie'; ' en surplus est vendue dans la CE '};
            app.GainparconsoAuMax = uieditfield(app.ValeurspourintgralitdusurplusVenduPanel, 'text');
            app.GainparconsoAuMax.Value = '?';
            app.GainparproducteursitoutelnergieensurplusvenduedanslaCELabel = uilabel(app.ValeurspourintgralitdusurplusVenduPanel);
            app.GainparproducteursitoutelnergieensurplusvenduedanslaCELabel.Text = {'Gain par producteur si toute l''énergie '; 'en surplus vendue dans la CE'};
            app.GainparproducteursitoutelnergieensurplusvenduedanslaCE = uieditfield(app.ValeurspourintgralitdusurplusVenduPanel, 'text');
            app.GainparproducteursitoutelnergieensurplusvenduedanslaCE.Value = '?';
            app.SimulationrapideDonnesphysiquesCEPanel = uipanel(app.SimulationCETab);
            app.SimulationrapideDonnesphysiquesCEPanel.Title = 'Simulation rapide : Données physiques C.E.  ';
            app.TypedeproductionDropDownLabel = uilabel(app.SimulationrapideDonnesphysiquesCEPanel);
            app.TypedeproductionDropDownLabel.Text = 'Type de production: ';
            app.TypedeproductionDropDown = uidropdown(app.SimulationrapideDonnesphysiquesCEPanel);
            app.TypedeproductionDropDown.Items = {'Solaire', 'Eolien', 'Cogen/Biometh.'};
            app.TypedeproductionDropDown.ValueChangedFcn = createCallbackFcn(app, @TypedeproductionDropDownValueChanged, true);
            app.TypedeproductionDropDown.Tag = 'CmbtypeProd';
            app.TypedeproductionDropDown.Value = 'Solaire';
            app.PuissanceinstalleLabel = uilabel(app.SimulationrapideDonnesphysiquesCEPanel);
            app.PuissanceinstalleLabel.Text = 'Puissance totale installée:';
            app.Puissancetotaleinstalle = uieditfield(app.SimulationrapideDonnesphysiquesCEPanel, 'numeric');
            app.Puissancetotaleinstalle.Limits = [0.1 1000000];
            app.Puissancetotaleinstalle.ValueChangedFcn = createCallbackFcn(app, @TypedeproductionDropDownValueChanged, true);
            app.Puissancetotaleinstalle.Tag = 'puissanceInstall';
            app.Puissancetotaleinstalle.Value = 4;
            app.NombredeproducteurEditFieldLabel = uilabel(app.SimulationrapideDonnesphysiquesCEPanel);
            app.NombredeproducteurEditFieldLabel.Text = 'Nombre de producteur:';
            app.Nombredeproducteur = uieditfield(app.SimulationrapideDonnesphysiquesCEPanel, 'numeric');
            app.Nombredeproducteur.Limits = [1 1000];
            app.Nombredeproducteur.Tag = 'nbrProd';
            app.Nombredeproducteur.Value = 1;
            app.ConsommationannuelledunproducteurEditFieldLabel = uilabel(app.SimulationrapideDonnesphysiquesCEPanel);
            app.ConsommationannuelledunproducteurEditFieldLabel.Text = 'Consommation annuelle d’un producteur :';
            app.Consommationannuelledunproducteur = uieditfield(app.SimulationrapideDonnesphysiquesCEPanel, 'numeric');
            app.Consommationannuelledunproducteur.Limits = [0 100000000];
            app.Consommationannuelledunproducteur.Tag = 'annuelConsoProd';
            app.Consommationannuelledunproducteur.Value = 3500;
            app.NombremaximundeconsommateurEditFieldLabel = uilabel(app.SimulationrapideDonnesphysiquesCEPanel);
            app.NombremaximundeconsommateurEditFieldLabel.Text = 'Nombre maximun de consommateur:';
            app.Nombremaximundeconsommateur = uieditfield(app.SimulationrapideDonnesphysiquesCEPanel, 'numeric');
            app.Nombremaximundeconsommateur.Limits = [1 10000];
            app.Nombremaximundeconsommateur.Tag = 'nbrMaxConso';
            app.Nombremaximundeconsommateur.Value = 10;
            app.ConsommationannuelledunconsommateurLabel = uilabel(app.SimulationrapideDonnesphysiquesCEPanel);
            app.ConsommationannuelledunconsommateurLabel.Text = 'Consommation annuelle d’un consommateur:';
            app.Consommationannuelledunconsommateur = uieditfield(app.SimulationrapideDonnesphysiquesCEPanel, 'numeric');
            app.Consommationannuelledunconsommateur.Limits = [1 100000000];
            app.Consommationannuelledunconsommateur.Tag = 'annuelConsoConso';
            app.Consommationannuelledunconsommateur.Value = 3500;
            app.kWLabel = uilabel(app.SimulationrapideDonnesphysiquesCEPanel);
            app.kWLabel.Text = ' kW';
            app.kWhLabel_5 = uilabel(app.SimulationrapideDonnesphysiquesCEPanel);
            app.kWhLabel_5.Text = ' kWh';
            app.kWhLabel_6 = uilabel(app.SimulationrapideDonnesphysiquesCEPanel);
            app.kWhLabel_6.Text = ' kWh';
            app.rightarrowProductionannuelletotaleLabel = uilabel(app.SimulationrapideDonnesphysiquesCEPanel);
            app.rightarrowProductionannuelletotaleLabel.Interpreter = 'tex';
            app.rightarrowProductionannuelletotaleLabel.Text = ' \rightarrow Production annuelle totale =';
            app.Affprodan = uilabel(app.SimulationrapideDonnesphysiquesCEPanel);
            app.Affprodan.Text = '3752 kWh';
            app.DimCEInterfacedoptimisationpourCommunautdnergieLabel = uilabel(app.SimulationCETab);
            app.DimCEInterfacedoptimisationpourCommunautdnergieLabel.Text = {' DimCE : Interface d’optimisation'; ' pour Communauté d’énergie '};
            app.tariffourisseur = uitable(app.SimulationCETab);
            app.tariffourisseur.ColumnName = {'Prix'};
            app.tariffourisseur.RowName = {};
            app.tariffourisseur.SelectionType = 'row';
            app.tariffourisseur.ColumnEditable = true;
            app.tariffourisseur.CellEditCallback = createCallbackFcn(app, @tariffourisseurCellEdit, true);
            app.Date = uilabel(app.SimulationCETab);
            app.Date.Text = '';
            app.Fournisseur = uilabel(app.SimulationCETab);
            app.Fournisseur.Text = 'Total Energie ';
            app.ChoixtypedesimulationButtonGroup = uibuttongroup(app.SimulationCETab);
            app.ChoixtypedesimulationButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ChoixtypedesimulationButtonGroupSelectionChanged, true);
            app.ChoixtypedesimulationButtonGroup.Title = 'Choix type de simulation :';
            app.SimulationrapideButton = uiradiobutton(app.ChoixtypedesimulationButtonGroup);
            app.SimulationrapideButton.Text = 'Simulation rapide';
            app.SimulationrapideButton.Value = true;
            app.SimulationsurCEpersonnaliseButton = uiradiobutton(app.ChoixtypedesimulationButtonGroup);
            app.SimulationsurCEpersonnaliseButton.Text = 'Simulation sur CE personnalisée';
            app.PersonnaliserlaCETab = uitab(app.TabGroup);
            app.PersonnaliserlaCETab.Title = 'Personnaliser la CE';
            app.NouveaumembrePanel = uipanel(app.PersonnaliserlaCETab);
            app.NouveaumembrePanel.Title = 'Nouveau membre :';
            app.BatteriedumembrePanel = uipanel(app.NouveaumembrePanel);
            app.BatteriedumembrePanel.Title = 'Batterie du membre :';
            app.CapacitbatterieinstalleLabel = uilabel(app.BatteriedumembrePanel);
            app.CapacitbatterieinstalleLabel.Text = 'Capacité batterie installée :';
            app.Capacitebat = uieditfield(app.BatteriedumembrePanel, 'numeric');
            app.Capacitebat.Limits = [0 100000000];
            app.kWhLabel_9 = uilabel(app.BatteriedumembrePanel);
            app.kWhLabel_9.Text = ' kWh';
            app.kWLabel_11 = uilabel(app.BatteriedumembrePanel);
            app.kWLabel_11.Text = ' kW';
            app.Puissancebat = uieditfield(app.BatteriedumembrePanel, 'numeric');
            app.Puissancebat.Limits = [0 1000000];
            app.PuissanceinstalleLabel_8 = uilabel(app.BatteriedumembrePanel);
            app.PuissanceinstalleLabel_8.Text = 'Puissance de charge/Décharge batterie:';
            app.ProductiondumembrePanel = uipanel(app.NouveaumembrePanel);
            app.ProductiondumembrePanel.Title = 'Production du membre:';
            app.kWLabel_6 = uilabel(app.ProductiondumembrePanel);
            app.kWLabel_6.Text = ' kW';
            app.PuissanceinstalleEol = uieditfield(app.ProductiondumembrePanel, 'numeric');
            app.PuissanceinstalleEol.Limits = [0 1000000];
            app.PuissanceinstalleEol.Tag = 'puissanceInstall';
            app.kWLabel_7 = uilabel(app.ProductiondumembrePanel);
            app.kWLabel_7.Text = ' kW';
            app.PuissanceinstalleSol = uieditfield(app.ProductiondumembrePanel, 'numeric');
            app.PuissanceinstalleSol.Limits = [0 1000000];
            app.PuissanceinstalleSol.Tag = 'puissanceInstall';
            app.kWLabel_8 = uilabel(app.ProductiondumembrePanel);
            app.kWLabel_8.Text = ' kW';
            app.PuissanceinstalleBio = uieditfield(app.ProductiondumembrePanel, 'numeric');
            app.PuissanceinstalleBio.Limits = [0 1000000];
            app.PuissanceinstalleBio.Tag = 'puissanceInstall';
            app.PuissanceinstalleLabel_3 = uilabel(app.ProductiondumembrePanel);
            app.PuissanceinstalleLabel_3.Text = 'Puissance installée Solaire:';
            app.PuissanceinstalleLabel_4 = uilabel(app.ProductiondumembrePanel);
            app.PuissanceinstalleLabel_4.Text = 'Puissance installée Cogen/Bio:';
            app.PuissanceinstalleLabel_5 = uilabel(app.ProductiondumembrePanel);
            app.PuissanceinstalleLabel_5.Text = 'Puissance installée Eolien:';
            app.kWhLabel_7 = uilabel(app.NouveaumembrePanel);
            app.kWhLabel_7.Text = ' kWh';
            app.Consommationannuelleparti = uieditfield(app.NouveaumembrePanel, 'numeric');
            app.Consommationannuelleparti.Limits = [0 100000000];
            app.Consommationannuelleparti.Tag = 'annuelConsoProd';
            app.Consommationannuelleparti.Value = 3500;
            app.Nom = uieditfield(app.NouveaumembrePanel, 'text');
            app.ConsommationannuelleprofilparticulierLabel = uilabel(app.NouveaumembrePanel);
            app.ConsommationannuelleprofilparticulierLabel.Text = 'Consommation annuelle profil particulier :';
            app.NomdumembreLabel = uilabel(app.NouveaumembrePanel);
            app.NomdumembreLabel.Text = 'Nom du membre:';
            app.Buttonajouter = uibutton(app.NouveaumembrePanel, 'push');
            app.Buttonajouter.ButtonPushedFcn = createCallbackFcn(app, @ButtonajouterPushed, true);
            app.Buttonajouter.Icon = fullfile(pathToMLAPP, 'user.png');
            app.Buttonajouter.Tooltip = {'Ajouter un membre '};
            app.Buttonajouter.Text = 'Ajouter membre';
            app.ConsommationannuelleprofilindustrielLabel = uilabel(app.NouveaumembrePanel);
            app.ConsommationannuelleprofilindustrielLabel.Text = 'Consommation annuelle profil industriel :';
            app.kWhLabel_8 = uilabel(app.NouveaumembrePanel);
            app.kWhLabel_8.Text = ' kWh';
            app.Consommationannuelleindust = uieditfield(app.NouveaumembrePanel, 'numeric');
            app.Consommationannuelleindust.Limits = [0 100000000];
            app.Consommationannuelleindust.Tag = 'annuelConsoProd';
            app.UITableCE = uitable(app.PersonnaliserlaCETab);
            app.UITableCE.ColumnName = {'Nom'; 'Conso. Parti (kWh/an)'; 'Conso. indust.(kWh/an)'; 'Puis. Sol. (kW)'; 'Puis. Eol. (kW)'; 'Puis. Cogen./Bio. (kW)'; 'Puis. Batt. (kW)'; 'Capa.Batt.(kWh)'};
            app.UITableCE.RowName = {};
            app.UITableCE.SelectionType = 'row';
            app.UITableCE.ColumnEditable = true;
            app.UITableCE.CellEditCallback = createCallbackFcn(app, @UITableCECellEdit, true);
            app.ButtonEffacer = uibutton(app.PersonnaliserlaCETab, 'push');
            app.ButtonEffacer.ButtonPushedFcn = createCallbackFcn(app, @ButtonEffacerPushed, true);
            app.ButtonEffacer.Icon = fullfile(pathToMLAPP, 'delete_icon-icons.com_52378.png');
            app.ButtonEffacer.Tooltip = {'Effacer le membre selectionné'};
            app.ButtonEffacer.Text = 'Effacer le membre ';
            app.ButtonExporter = uibutton(app.PersonnaliserlaCETab, 'push');
            app.ButtonExporter.ButtonPushedFcn = createCallbackFcn(app, @ButtonExporterPushed, true);
            app.ButtonExporter.Icon = fullfile(pathToMLAPP, 'export_icon-icons.com_52383.png');
            app.ButtonExporter.Tooltip = {'Exporter la CE au format .xls'};
            app.ButtonExporter.Text = 'Exporter Fichier CE';
            app.ButtonImporter = uibutton(app.PersonnaliserlaCETab, 'push');
            app.ButtonImporter.ButtonPushedFcn = createCallbackFcn(app, @ButtonImporterPushed, true);
            app.ButtonImporter.Icon = fullfile(pathToMLAPP, 'import_icon-icons.com_52387.png');
            app.ButtonImporter.Tooltip = {'importer un Fichier CE au format .xls'};
            app.ButtonImporter.Text = 'Importer Fichier CE';
            app.ButtonSupprimer = uibutton(app.PersonnaliserlaCETab, 'push');
            app.ButtonSupprimer.ButtonPushedFcn = createCallbackFcn(app, @ButtonSupprimerPushed, true);
            app.ButtonSupprimer.Icon = fullfile(pathToMLAPP, 'cancel_icon-icons.com_52401.png');
            app.ButtonSupprimer.Tooltip = {'Effacer toute la CE'};
            app.ButtonSupprimer.Text = 'Effacer la CE';
            app.InformationCEPanel = uipanel(app.PersonnaliserlaCETab);
            app.InformationCEPanel.Title = 'Information CE:';
            app.UIAxesCE = uiaxes(app.InformationCEPanel);
            title(app.UIAxesCE, 'Energie partagée en fonction du mois')
            xlabel(app.UIAxesCE, 'Mois')
            ylabel(app.UIAxesCE, 'Kwh')
            zlabel(app.UIAxesCE, 'Z')
            app.UITable3 = uitable(app.InformationCEPanel);
            app.UITable3.ColumnName = {'Nom'; 'Autosuff.'; 'Autoconso.'; 'Achat à la CE/ Gain CE'; 'Surplus Vendu CE/Gain CE'; 'Gain grâce CE'};
            app.UITable3.RowName = {};
            app.UITable3.SelectionType = 'row';
            app.ProductionTotalCELabel = uilabel(app.InformationCEPanel);
            app.ProductionTotalCELabel.Text = 'Production Totale ';
            app.ProductionTotaleEditField = uieditfield(app.InformationCEPanel, 'text');
            app.ConsommationTotalCELabel = uilabel(app.InformationCEPanel);
            app.ConsommationTotalCELabel.Text = 'Consommation Totale ';
            app.ConsommationTotaleEditField = uieditfield(app.InformationCEPanel, 'text');
            app.NombredemembreEditFieldLabel = uilabel(app.InformationCEPanel);
            app.NombredemembreEditFieldLabel.Text = 'Nombre de membre  ';
            app.NombredemembreEditField = uieditfield(app.InformationCEPanel, 'text');
            app.VolumeTotalEchangCELabel = uilabel(app.InformationCEPanel);
            app.VolumeTotalEchangCELabel.Text = 'Volume Total Echangé CE';
            app.VolumeTotalEchangCEEditField = uieditfield(app.InformationCEPanel, 'text');
            app.DimensionnementinstallationetbatterieTab = uitab(app.TabGroup);
            app.DimensionnementinstallationetbatterieTab.Title = 'Dimensionnement installation et batterie';
            app.DimensionnementInstallationenfonctiondelaconsommationPanel = uipanel(app.DimensionnementinstallationetbatterieTab);
            app.DimensionnementInstallationenfonctiondelaconsommationPanel.Title = 'Dimensionnement Installation en fonction de la consommation.';
            app.UIAxesdiminst = uiaxes(app.DimensionnementInstallationenfonctiondelaconsommationPanel);
            title(app.UIAxesdiminst, 'Title')
            xlabel(app.UIAxesdiminst, 'Puissance totale  installée:')
            ylabel(app.UIAxesdiminst, 'amortisement en annee ')
            zlabel(app.UIAxesdiminst, 'Z')
            app.kWhLabel_10 = uilabel(app.DimensionnementInstallationenfonctiondelaconsommationPanel);
            app.kWhLabel_10.Text = ' kWh';
            app.TypedeproductionDropDown_2Label = uilabel(app.DimensionnementInstallationenfonctiondelaconsommationPanel);
            app.TypedeproductionDropDown_2Label.Text = 'Type de production: ';
            app.TypedeproductionDropDown_2 = uidropdown(app.DimensionnementInstallationenfonctiondelaconsommationPanel);
            app.TypedeproductionDropDown_2.Items = {'Solaire', 'Eolien', 'Cogen/Biometh.'};
            app.TypedeproductionDropDown_2.ValueChangedFcn = createCallbackFcn(app, @TypedeproductionDropDown_2ValueChanged, true);
            app.TypedeproductionDropDown_2.Value = 'Solaire';
            app.ConsommationannuelleLabel = uilabel(app.DimensionnementInstallationenfonctiondelaconsommationPanel);
            app.ConsommationannuelleLabel.Text = 'Consommation annuelle:';
            app.ConsommationannuelleDim = uieditfield(app.DimensionnementInstallationenfonctiondelaconsommationPanel, 'numeric');
            app.ConsommationannuelleDim.Limits = [0 100000000];
            app.ConsommationannuelleDim.ValueChangedFcn = createCallbackFcn(app, @TypedeproductionDropDown_2ValueChanged, true);
            app.ConsommationannuelleDim.Value = 3500;
            app.kWLabel_10 = uilabel(app.DimensionnementInstallationenfonctiondelaconsommationPanel);
            app.kWLabel_10.Text = ' kW';
            app.rightarrowProductionannuelletotaleLabel_2 = uilabel(app.DimensionnementInstallationenfonctiondelaconsommationPanel);
            app.rightarrowProductionannuelletotaleLabel_2.Interpreter = 'tex';
            app.rightarrowProductionannuelletotaleLabel_2.Text = ' \rightarrow Production annuelle totale =';
            app.Affprodan_2 = uilabel(app.DimensionnementInstallationenfonctiondelaconsommationPanel);
            app.Affprodan_2.Text = '3752 kWh';
            app.PuissanceinstalleLabel_7 = uilabel(app.DimensionnementInstallationenfonctiondelaconsommationPanel);
            app.PuissanceinstalleLabel_7.Text = 'Puissance totale a installée:';
            app.Puissancetotaleainstalle = uieditfield(app.DimensionnementInstallationenfonctiondelaconsommationPanel, 'numeric');
            app.Puissancetotaleainstalle.Limits = [0.1 1000000];
            app.Puissancetotaleainstalle.Editable = 'off';
            app.Puissancetotaleainstalle.Value = 4;
            app.downarrowdownarrowdownarrowLabel = uilabel(app.DimensionnementInstallationenfonctiondelaconsommationPanel);
            app.downarrowdownarrowdownarrowLabel.Interpreter = 'tex';
            app.downarrowdownarrowdownarrowLabel.Text = ' \downarrow  \downarrow  \downarrow ';
            app.DimensionnementBatteriePanel = uipanel(app.DimensionnementinstallationetbatterieTab);
            app.DimensionnementBatteriePanel.Title = 'Dimensionnement Batterie';
            % Show the figure after all components are created
            app.DimCEUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DimCE124

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.DimCEUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DimCEUIFigure)
        end
    end
end